classdef Linear_Regression_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.EditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.EditField
        PleaseenterdataLabel          matlab.ui.control.Label
        within2squaredbracketsLabel   matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        Label                         matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ChoosethemodeltofitthedatatoLabel  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        StartButton                   matlab.ui.control.Button
        auto_text                     matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        SquarederrorSrEditFieldLabel  matlab.ui.control.Label
        Sr                            matlab.ui.control.NumericEditField
        SquarederrorwithmeanStEditFieldLabel  matlab.ui.control.Label
        St                            matlab.ui.control.NumericEditField
        StandarddeviationSyEditFieldLabel  matlab.ui.control.Label
        Sy                            matlab.ui.control.NumericEditField
        CorrelationcoefficientrLabel  matlab.ui.control.Label
        r                             matlab.ui.control.NumericEditField
        equation                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        X;
        Y;
        X_per;
        best;
    end
    
    methods (Access = private)
        
        function Auto_func(app, x1 ,y1)
            r_co = [];
            n = length(x1);
            sum_x = sum(x1);
            sum_x_2 = sum(x1.^2);
            sum_y = sum(y1);
            sum_xy = sum(x1.*y1);
            
            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
            St_temp = sum( ( y1 - sum_y/n ).^2 );
            Sr_temp = sum( ( y1 - A(1) - A(2).*x1 ) .^2 );
            r_co(1) = sqrt( (St_temp-Sr_temp)/St_temp) * 100; %linear
            
            y_temp = log(y1);
            n = length(x1);
            sum_x = sum(x1);
            sum_x_2 = sum(x1.^2);
            sum_y = sum(y_temp);
            sum_xy = sum(x1.*y_temp);
            
            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
            St_temp = sum( ( y_temp - sum_y/n ).^2 );
            Sr_temp = sum( ( y_temp - A(1) - A(2).*x1 ) .^2 );
            r_co(2) = sqrt( (St_temp-Sr_temp)/St_temp) * 100; %exp
            
            x_temp = log10(x1);
            y_temp = log10(y1);
            n = length(x_temp);
            sum_x = sum(x_temp);
            sum_x_2 = sum(x_temp.^2);
            sum_y = sum(y_temp);
            sum_xy = sum(x_temp.*y_temp);
            
            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
            St_temp = sum( ( y_temp - sum_y/n ).^2 );
            Sr_temp = sum( ( y_temp - A(1) - A(2).*x_temp ) .^2 );
            r_co(3) = sqrt( (St_temp-Sr_temp)/St_temp ) * 100; %power
            
            x_temp = 1./x1;
            y_temp = 1./y1;
            n = length(x_temp);
            sum_x = sum(x_temp);
            sum_x_2 = sum(x_temp.^2);
            sum_y = sum(y_temp);
            sum_xy = sum(x_temp.*y_temp);
            
            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
            St_temp = sum( ( y_temp - sum_y/n ).^2 );
            Sr_temp = sum( ( y_temp - A(1) - A(2).*x_temp ) .^2 );
            r_co(4) = sqrt( (St_temp-Sr_temp)/St_temp) * 100; %G.R Model
            
            app.best = find(r_co==max(r_co));
            return
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartButton.Enable = 'off';
            app.equation.Text = 'Model Equation';
            app.Label.Text = 'Checking Data';
        end

        % Value changing function: XEditField
        function XEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.X = str2num(changingValue);
        end

        % Value changing function: YEditField
        function YEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.Y = str2num(changingValue);
            
            if length(app.X) == length(app.Y)
                app.StartButton.Enable = 'on';
                app.Label.Text = 'Data is valid!';
                app.Lamp.Color = 'green';
                plot(app.UIAxes,app.X,app.Y,'o');
            else
                app.Label.Text = 'Not valid! (Missing Data)';
                app.StartButton.Enable = 'off';
                if length(app.X) > length(app.Y)
                    app.Label.Text = 'Not valid! (X > Y)';
                else
                    app.Label.Text = 'Not valid! (Y > X)';
                end
                app.Lamp.Color = 'red';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.X_per = linspace(min(app.X),max(app.X),1000);  %will be used for plotting the curve later
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Computing...';
            n = length(app.X);
            sum_x = sum(app.X);
            sum_x_2 = sum(app.X.^2);
            sum_y = sum(app.Y);
            sum_xy = sum(app.X.*app.Y);
            A = [];
            
            hold(app.UIAxes,"on");
            switch app.DropDown.Value
                case '<Choose>'
                    app.equation.Text = 'No model was choosen!';
                    
                case 'Linear Regression'
                    A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                    Y_new = A(1) + A(2).*app.X_per;
                    plot(app.UIAxes,app.X_per,Y_new);
                    %fprintf('\nEquation is Y=(%.4f)+(%.4f)X',A(1),A(2));
                    app.equation.Text = sprintf('Equation is Y=(%.4f)+(%.4f)X',A(1),A(2)');
                    title(app.UIAxes,'Linear Model');
                    
                case 'Exponential Model'
                    app.Y = log(app.Y);
                    sum_y = sum(app.Y);
                    sum_xy = sum(app.X.*app.Y);
                    
                    A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                    a = exp(A(1));
                    b = A(2);
                    Y_new = a * exp(b*app.X_per);
                    plot(app.UIAxes,app.X_per,Y_new);
                    app.equation.Text = sprintf('Equation is Y=(%.4f).e^(%.4fX)',a,b');
                    title(app.UIAxes,'Exponential Model');
                    
                case 'Power Model'
                    app.X = log10(app.X);
                    app.Y = log10(app.Y);
                    n = length(app.X);
                    sum_x = sum(app.X);
                    sum_x_2 = sum(app.X.^2);
                    sum_y = sum(app.Y);
                    sum_xy = sum(app.X.*app.Y);
                    
                    A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                    a = 10^(A(1));
                    b = A(2);
                    Y_new = a * (app.X_per.^b);
                    plot(app.UIAxes,app.X_per,Y_new);
                    app.equation.Text = sprintf('Equation is Y=(%.4f).X^(%.4f)',a,b');
                    title(app.UIAxes,'Power Model');
                    
                case 'Growth Rate Model'
                    app.X = 1./app.X;
                    app.Y = 1./app.Y;
                    n = length(app.X);
                    sum_x = sum(app.X);
                    sum_x_2 = sum(app.X.^2);
                    sum_y = sum(app.Y);
                    sum_xy = sum(app.X.*app.Y);
                    
                    A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                    a = 1/A(1);
                    b = A(2)*a;
                    Y_new = (a * app.X_per)./(app.X_per + b);
                    plot(app.UIAxes,app.X_per,Y_new);
                    app.equation.Text = sprintf('Equation is Y=%.4fX/(%.4f)+X',a,b');
                    title(app.UIAxes,'Growth Rate Model');
                    
                case 'Auto'
                    Auto_func(app,app.X,app.Y);
                    switch app.best
                        case 1
                            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                            Y_new = A(1) + A(2).*app.X_per;
                            plot(app.UIAxes,app.X_per,Y_new);
                            %fprintf('\nEquation is Y=(%.4f)+(%.4f)X',A(1),A(2));
                            app.equation.Text = sprintf('Equation is Y=(%.4f)+(%.4f)X',A(1),A(2)');
                            title(app.UIAxes,'Best Fit is the Linear Model');
                            
                        case 2
                            app.Y = log(app.Y);
                            sum_y = sum(app.Y);
                            sum_xy = sum(app.X.*app.Y);
                            
                            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                            a = exp(A(1));
                            b = A(2);
                            Y_new = a * exp(b*app.X_per);
                            plot(app.UIAxes,app.X_per,Y_new);
                            app.equation.Text = sprintf('Equation is Y=(%.4f).e^(%.4fX)',a,b');
                            title(app.UIAxes,'Best Fit is the Exponential Model');
                            
                        case 3
                            app.X = log10(app.X);
                            app.Y = log10(app.Y);
                            n = length(app.X);
                            sum_x = sum(app.X);
                            sum_x_2 = sum(app.X.^2);
                            sum_y = sum(app.Y);
                            sum_xy = sum(app.X.*app.Y);
                            
                            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                            a = 10^(A(1));
                            b = A(2);
                            Y_new = a * (app.X_per.^b);
                            plot(app.UIAxes,app.X_per,Y_new);
                            app.equation.Text = sprintf('Equation is Y=(%.4f).X^(%.4f)',a,b');
                            title(app.UIAxes,'Best Fit is the Power Model');
                            
                        case 4
                            app.X = 1./app.X;
                            app.Y = 1./app.Y;
                            n = length(app.X);
                            sum_x = sum(app.X);
                            sum_x_2 = sum(app.X.^2);
                            sum_y = sum(app.Y);
                            sum_xy = sum(app.X.*app.Y);
                            
                            A = [sum_y sum_xy]/[n sum_x;sum_x sum_x_2];
                            a = 1/A(1);
                            b = A(2)*a;
                            Y_new = (a * app.X_per)./(app.X_per + b);
                            plot(app.UIAxes,app.X_per,Y_new);
                            app.equation.Text = sprintf('Equation is Y=%.4fX/(%.4f)+X',a,b');
                            title(app.UIAxes,'Best Fit is the Growth Rate Model');
                    end
            end
            
            app.St.Value = sum( ( app.Y - sum_y/n ).^2 );
            app.Sr.Value = sum( ( app.Y - A(1) - A(2).*app.X ) .^2 );
            app.r.Value = sqrt( ( double(app.St.Value) - double(app.Sr.Value) ) / double(app.St.Value) ) * 100; %correlation coefficient
            app.Sy.Value = sqrt( double(app.St.Value) / (n-1) );  %Std of the data
            
            pause(1);
            app.StartButton.Text = 'Done!';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case '<Choose>'
                    app.auto_text.Text = '';
                case 'Linear Regression'
                    app.auto_text.Text = sprintf('This mode will fit your data into\na linear model');
                case 'Exponential Model'
                    app.auto_text.Text = sprintf('This mode will fit your data into\nan Exponential Model');
                case 'Power Model'
                    app.auto_text.Text = sprintf('This mode will fit your data into\na Power model');
                case 'Growth Rate Model'
                    app.auto_text.Text = sprintf('This mode will fit your data into\na Growth Rate model');
                case 'Auto'
                    app.auto_text.Text = sprintf('This mode will let the program compute\nthe best fit automatically');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel_2 = uipanel(app.UIFigure);
            app.XEditFieldLabel = uilabel(app.Panel_2);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel_2, 'text');
            app.XEditField.ValueChangingFcn = createCallbackFcn(app, @XEditFieldValueChanging, true);
            app.YEditFieldLabel = uilabel(app.Panel_2);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel_2, 'text');
            app.YEditField.ValueChangingFcn = createCallbackFcn(app, @YEditFieldValueChanging, true);
            app.PleaseenterdataLabel = uilabel(app.Panel_2);
            app.PleaseenterdataLabel.Text = 'Please enter data';
            app.within2squaredbracketsLabel = uilabel(app.Panel_2);
            app.within2squaredbracketsLabel.Text = 'within 2 squared brackets!';
            app.Lamp = uilamp(app.Panel_2);
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.ChoosethemodeltofitthedatatoLabel = uilabel(app.Panel);
            app.ChoosethemodeltofitthedatatoLabel.Text = {'Choose the model to'; 'fit the data to!'};
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'<Choose>', 'Linear Regression', 'Exponential Model', 'Power Model', 'Growth Rate Model', 'Auto'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '<Choose>';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.auto_text = uilabel(app.Panel);
            app.auto_text.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SquarederrorSrEditFieldLabel = uilabel(app.UIFigure);
            app.SquarederrorSrEditFieldLabel.Text = 'Squared error (Sr)';
            app.Sr = uieditfield(app.UIFigure, 'numeric');
            app.SquarederrorwithmeanStEditFieldLabel = uilabel(app.UIFigure);
            app.SquarederrorwithmeanStEditFieldLabel.Text = 'Squared error with mean (St)';
            app.St = uieditfield(app.UIFigure, 'numeric');
            app.StandarddeviationSyEditFieldLabel = uilabel(app.UIFigure);
            app.StandarddeviationSyEditFieldLabel.Text = 'Standard deviation (Sy)';
            app.Sy = uieditfield(app.UIFigure, 'numeric');
            app.CorrelationcoefficientrLabel = uilabel(app.UIFigure);
            app.CorrelationcoefficientrLabel.Text = 'Correlation coefficient (r%)';
            app.r = uieditfield(app.UIFigure, 'numeric');
            app.equation = uilabel(app.UIFigure);
            app.equation.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Linear_Regression_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end